classdef DisplayGUI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        DisplayTable    matlab.ui.control.Table
        Clear_1         matlab.ui.control.Button
        SaveLocation    matlab.ui.container.Panel
        SaveLoc         matlab.ui.control.EditField
        SAVE            matlab.ui.control.Button
        RUN             matlab.ui.control.Button
        uipanel6        matlab.ui.container.Panel
        text25          matlab.ui.control.Label
        MiningDuration  matlab.ui.control.EditField
        text24          matlab.ui.control.Label
        DryMass         matlab.ui.control.EditField
        PropMass        matlab.ui.control.EditField
        DryMassText     matlab.ui.control.Label
        text14          matlab.ui.control.Label
        Isp             matlab.ui.control.EditField
        Thrust          matlab.ui.control.EditField
        text15          matlab.ui.control.Label
        Clear           matlab.ui.container.Panel
        text11          matlab.ui.control.Label
        Uncertainty     matlab.ui.control.EditField
        text5           matlab.ui.control.Label
        text4           matlab.ui.control.Label
        ReturnTraj      matlab.ui.control.DropDown
        OutboundTraj    matlab.ui.control.DropDown
        uipanel1        matlab.ui.container.Panel
        NumOfSamples    matlab.ui.control.EditField
        text22          matlab.ui.control.Label
        LoweBoundTime   matlab.ui.control.Label
        UBStartTime     matlab.ui.control.EditField
        LBStartTime     matlab.ui.control.EditField
        UpperBoundTime  matlab.ui.control.Label
    end

    
    methods (Access = private)
        function MiningDur_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to MiningDur (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function SaveLocation_Callback(app, hObject, eventdata, handles)
            % hObject    handle to SaveLocation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Hints: get(hObject,'String') returns contents of SaveLocation as text
            %        str2double(get(hObject,'String')) returns contents of SaveLocation as a double
            OutboundTrajs = handles.OutboundTraj.String;
        end
        
        function SaveLocation_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to SaveLocation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.DisplayTable.ColumnFormat = {[]};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DisplayGUI_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to DisplayGUI (see VARARGIN)
            
            % Choose default command line output for DisplayGUI
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Clear_1
        function Clear_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Clear (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            %OutboundTrajs = handles.OutboundTraj.String;
            %ReturnTrajs = handles.ReturnTraj.String;
            set(handles.LBStartTime,'string','07-Jun-2022 00:00:00');
            set(handles.UBStartTime,'string','07-Jun-2022 00:00:00');
            set(handles.NumOfSamples,'string','');
            set(handles.OutboundTraj,'Value',1);
            set(handles.ReturnTraj,'Value',1);
            set(handles.Uncertainty,'string','');
            set(handles.Isp,'string','');
            set(handles.Thrust,'string','');
            set(handles.DryMass,'string','');
            set(handles.PropMass,'string','');
            set(handles.MiningDuration,'string','');
            set(handles.SaveLoc,'string','');
        end

        % Button pushed function: RUN
        function RUN_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to RUN (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            LBStartTime = get(handles.LBStartTime,'string'); %Lower Bound for Start Time
            UBStartTime = get(handles.UBStartTime,'string'); %Upper Bound for Start Time
            NumSamples = str2num(get(handles.NumOfSamples,'string')); %Number of Samples Taken
            tFinal = datenum(UBStartTime); %Converting Final Start Time to DateNum
            tInit = datenum(LBStartTime); %Converting Initial Start Time to DateNum
            dT = (tFinal - tInit)/NumSamples; %Calculating Time Step
            SampleDates(1) = tInit; %First Date of the Vector is Lower Bound Start Time
            SampleDates(str2num(get(handles.NumOfSamples,'string'))) = tFinal; %Last Date of the Vector is Upper Bound Start Time
            
            tb = tInit; %Initializing time
            WB = waitbar((0)/NumSamples,'I am Loading'); %Creating Progress Bar
            
            for i = 2:str2num(get(handles.NumOfSamples,'string'))-1 %Stepping through and Adding Values of to Start Time Vector
            
                if getappdata(WB,'canceling')% Check for clicked Cancel button
                    break
                end
            
                waitbar((i-1)/NumSamples,WB,'Initializing Start Times');
                tb = tb + dT;
                SampleDates(i) = tb;
            
            end
            close(WB)
            
            %---Converts Back to String Vector for Time in STK Format
            SampleDates = datetime(SampleDates, 'Format','yyyy MM dd HH:mm:ss:SSS','ConvertFrom', 'datenum');
            
            %---Displaying Saved Values Into GUI Table
            DisplayTable = cellstr(SampleDates');
            set(handles.DisplayTable,'Data',DisplayTable,'ColumnName',{'Start Time'});
            
            
            %---Assigning Selected Outbound Traj---%
            OutboundTrajs = handles.OutboundTraj.String;
            OutboundTrajIndex = handles.OutboundTraj.Value;
            OutboundTraj = OutboundTrajs{OutboundTrajIndex};
            
            %---Assigning Selected Return Traj---%
            ReturnTrajs = handles.ReturnTraj.String;
            ReturnTrajIndex = handles.ReturnTraj.Value;
            ReturnTraj = ReturnTrajs{ReturnTrajIndex};
            
            %---Assigning Spacecraft Parameters---%
            EngineIsp = get(handles.Isp,'Value');
            EngineThrust = get(handles.Thrust,'Value');
            DryMass = get(handles.DryMass,'Value');
            DryMass = get(handles.PropMass,'Value');
            MiningDur = get(handles.MiningDuration,'Value');
            
            switch OutboundTraj %Nested Switch Case for Outbound/Return Trajs
                case 'Hayabusa Mission Profile'
                    switch ReturnTraj
                        case 'Direct Return to Earth'
                        case 'EGA to Return to Earth'
                    end
                case 'Earth Escape Direct to Asteroid Plane'
                    switch ReturnTraj
                        case 'Direct Return to Earth'
                        case 'EGA to Return to Earth'
                    end
                case 'EGA to Asteroid Plane'
                    switch ReturnTraj
                        case 'Direct Return to Earth'
                        case 'EGA to Return to Earth'
                    end
                case 'EGA in Earth Plane to Raise Orbit'
                    switch ReturnTraj
                        case 'Direct Return to Earth'
                        case 'EGA to Return to Earth'
                    end
                case 'Direct Trajectory'
                    switch ReturnTraj
                        case 'Direct Return to Earth'
                        case 'EGA to Return to Earth'
                    end
            end
        end

        % Button pushed function: SAVE
        function SAVE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to SAVE (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            LBStartTime = get(handles.LBStartTime,'string'); %Lower Bound for Start Time
            UBStartTime = get(handles.UBStartTime,'string'); %Upper Bound for Start Time
            NumSamples = str2num(get(handles.NumOfSamples,'string')); %Number of Samples Taken
            tFinal = datenum(UBStartTime); %Converting Final Start Time to DateNum
            tInit = datenum(LBStartTime); %Converting Initial Start Time to DateNum
            dT = (tFinal - tInit)/NumSamples; %Calculating Time Step
            SampleDates(1) = tInit; %First Date of the Vector is Lower Bound Start Time
            SampleDates(str2num(get(handles.NumOfSamples,'string'))) = tFinal; %Last Date of the Vector is Upper Bound Start Time
            
            tb = tInit; %Initializing time
            for i = 2:str2num(get(handles.NumOfSamples,'string'))-1 %Stepping through and Adding Values of to Start Time Vector
                tb = tb + dT;
                SampleDates(i) = tb;
            end
            
            SampleDates = datetime(SampleDates, 'Format','yyyy MM dd HH:mm:ss:SSS','ConvertFrom', 'datenum') %Converts Back to String Vector for Time in STK Format
            DateString = string(SampleDates)'
            [file,path] = uiputfile('*.xlsx');
            filename = fullfile(path,file);
            xlswrite(filename,DateString);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'DisplayGUI';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'SIMULATION START TIME';
            app.uipanel1.Tag = 'uipanel1';
            app.UpperBoundTime = uilabel(app.uipanel1);
            app.UpperBoundTime.Tag = 'UpperBoundTime';
            app.UpperBoundTime.Text = 'Upper Bound';
            app.LBStartTime = uieditfield(app.uipanel1, 'text');
            app.LBStartTime.Tag = 'LBStartTime';
            app.LBStartTime.Value = '07-Jun-2022 00:00:00';
            app.UBStartTime = uieditfield(app.uipanel1, 'text');
            app.UBStartTime.Tag = 'UBStartTime';
            app.UBStartTime.Value = '07-Jun-2022 00:00:00';
            app.LoweBoundTime = uilabel(app.uipanel1);
            app.LoweBoundTime.Tag = 'LoweBoundTime';
            app.LoweBoundTime.Text = 'Lower Bound';
            app.text22 = uilabel(app.uipanel1);
            app.text22.Tag = 'text22';
            app.text22.Text = '# of Samples';
            app.NumOfSamples = uieditfield(app.uipanel1, 'text');
            app.NumOfSamples.Tag = 'NumOfSamples';
            app.Clear = uipanel(app.figure1);
            app.Clear.Title = 'TRAJECTORY TYPE';
            app.Clear.Tag = 'Clear';
            app.OutboundTraj = uidropdown(app.Clear);
            app.OutboundTraj.Items = {'', 'Hayabusa Mission Profile', 'Earth Escape Direct to Asteroid Plane', 'EGA to Asteroid Plane', 'EGA in Earth Plane to Raise Orbit', 'Direct Trajectory'};
            app.OutboundTraj.Tag = 'OutboundTraj';
            app.OutboundTraj.Value = '';
            app.ReturnTraj = uidropdown(app.Clear);
            app.ReturnTraj.Items = {'', 'Direct Return to Earth', 'EGA to Return to Earth'};
            app.ReturnTraj.Tag = 'ReturnTraj';
            app.ReturnTraj.Value = '';
            app.text4 = uilabel(app.Clear);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Outbound Trajectory';
            app.text5 = uilabel(app.Clear);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Return Trajectory';
            app.Uncertainty = uieditfield(app.Clear, 'text');
            app.Uncertainty.Tag = 'Uncertainty';
            app.text11 = uilabel(app.Clear);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Uncertainty';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'SPACECRAFT PARAMETERS';
            app.uipanel6.Tag = 'uipanel6';
            app.text15 = uilabel(app.uipanel6);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Isp';
            app.Thrust = uieditfield(app.uipanel6, 'text');
            app.Thrust.Tag = 'Thrust';
            app.Isp = uieditfield(app.uipanel6, 'text');
            app.Isp.Tag = 'Isp';
            app.text14 = uilabel(app.uipanel6);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Thrust';
            app.DryMassText = uilabel(app.uipanel6);
            app.DryMassText.Tag = 'DryMassText';
            app.DryMassText.Text = {'Dry '; 'Mass'};
            app.PropMass = uieditfield(app.uipanel6, 'text');
            app.PropMass.Tag = 'PropMass';
            app.DryMass = uieditfield(app.uipanel6, 'text');
            app.DryMass.Tag = 'DryMass';
            app.text24 = uilabel(app.uipanel6);
            app.text24.Tag = 'text24';
            app.text24.Text = {'Prop'; 'Mass'};
            app.MiningDuration = uieditfield(app.uipanel6, 'text');
            app.MiningDuration.Tag = 'MiningDuration';
            app.text25 = uilabel(app.uipanel6);
            app.text25.Tag = 'text25';
            app.text25.Text = {'Mining'; 'Duration'};
            app.RUN = uibutton(app.figure1, 'push');
            app.RUN.ButtonPushedFcn = createCallbackFcn(app, @RUN_Callback, true);
            app.RUN.Tag = 'RUN';
            app.RUN.Text = 'RUN';
            app.SaveLocation = uipanel(app.figure1);
            app.SaveLocation.Title = 'SAVE DATA';
            app.SaveLocation.Tag = 'SaveLocation';
            app.SAVE = uibutton(app.SaveLocation, 'push');
            app.SAVE.ButtonPushedFcn = createCallbackFcn(app, @SAVE_Callback, true);
            app.SAVE.Tag = 'SAVE';
            app.SAVE.Text = 'SAVE';
            app.SaveLoc = uieditfield(app.SaveLocation, 'text');
            app.SaveLoc.Tag = 'SaveLoc';
            app.Clear_1 = uibutton(app.figure1, 'push');
            app.Clear_1.ButtonPushedFcn = createCallbackFcn(app, @Clear_Callback, true);
            app.Clear_1.Tag = 'Clear_1';
            app.Clear_1.Text = 'CLEAR';
            app.DisplayTable = uitable(app.figure1);
            app.DisplayTable.Tag = 'DisplayTable';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DisplayGUI_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)DisplayGUI_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end